classdef app_outlook < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        startButton               matlab.ui.control.Button
        CalculateButton           matlab.ui.control.Button
        TextArea2                 matlab.ui.control.TextArea
        SelectyearSlider          matlab.ui.control.Slider
        SelectyearSliderLabel     matlab.ui.control.Label
        CountryNameDropDown       matlab.ui.control.DropDown
        CountryNameDropDownLabel  matlab.ui.control.Label
        DisplayTextArea           matlab.ui.control.TextArea
        DisplayTextAreaLabel      matlab.ui.control.Label
        UITable                   matlab.ui.control.Table
        SolarEfficentLamp         matlab.ui.control.Lamp
        SolarEfficentLampLabel    matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
Property 
data3 = readtable("Data_3.csv");
Solar_Consumption = readtable('Solar_Consumption.csv');

data8 = readtable("Data_8.csv");

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DisplayTextArea
        function DisplayTextAreaValueChanged(app, event)
            value = app.DisplayTextArea.Value;


        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)

           %lamp being called back by a BUTTON        
            name_country = app.data3(:,1);
            name_country = table2array(name_country);
            energy_percentage = app.data3(:,4);
            energy_percentage = table2array(energy_percentage);
            year_data3 = app.data3(:,3);
            year_data3 = table2array(year_data3);
            
            %finding the total enegy percentage of all the latest years.
            
            %masking the latest year 2019
            mask1 = (year_data3 == 2019);
            
            count_2019 = sum(mask1);
            total_2019 = sum(energy_percentage(mask1));
            avg_newyear_percentage = total_2019 / count_2019;
            
            %Get the name of the country from the drop down component not
            %done yet

            country_name = app.CountryNameDropDown.Value;
                        
            %location of the 2019 year and the country name
            loc_2019 = find(ismember(year_data3,2019) & ismember(name_country,country_name));
            
            %seprating the solar enegy value of the year so that i can be compared to
            %the avg
            
            solar_val_2019_selectedcountry = energy_percentage(loc_2019 , 1);
            
            
            % calculating the percent increase or decrease while compared to avgg
            
            percent_change = (solar_val_2019_selectedcountry / avg_newyear_percentage ) * 100;
            
            if percent_change > 125
                %take upper high thus dark green
                app.SolarEfficentLamp.Color = [0.25,0.71,0.25];
            elseif percent_change >= 100 && percent_change <= 125
                %lower uper value
                app.SolarEfficentLamp.Color = [0.64,0.81,0.64]	;
            elseif percent_change >= 75 && percent_change < 100
                app.SolarEfficentLamp.Color = [1.00,0.41,0.16]	;
            elseif percent_change >= 0 && percent_change < 75
                app.SolarEfficentLamp.Color = [1.00,0.00,0.00]	;
            end


 making the slider round up (always an integer);
app.SelectyearSlider.Value = ceil(app.SelectyearSlider.Value);

%slider year with solar consumption tera watt.(new
%data)
Countries = app.Solar_Consumption(:,1);
Countries = table2array(Countries);
years = app.Solar_Consumption(:,3);
years = table2array(years);

terrawatt = app.Solar_Consumption(:,4);
terrawatt = table2array(terrawatt);
countryname = app.CountryNameDropDown.Value;
selected_year = app.SelectyearSlider.Value;
%This is finding the location of the selected country with the
loc_selected_year = find(ismember(years,selected_year) & ismember(Countries,countryname));
%this is outputting the year and the terrawatt of the year from the
%location
output_year = selected_year;
output_terawatt = terrawatt(loc_selected_year);

Tablevariable = ["Year" , output_year ; "Solar consumption / [TWh]" , output_terawatt];
app.UITable.Data = Tablevariable;


%Inputing data into table.
cname = app.CountryNameDropDown.Value;
%User selects year
year_selected = app.SelectyearSlider.Value;
 

            name_country = app.data8(:,1);
            name_country = table2array(name_country);
            year_data8 = app.data8(:,3);
            year_data8 = table2array(year_data8);
            energy_production_hydro = app.data8(:,4);
            energy_production_hydro = table2array(energy_production_hydro);
            energy_production_wind = app.data8(:,7);
            energy_production_wind = table2array(energy_production_wind);
            energy_production_solar = app.data8(:,6);
            energy_production_solar = table2array(energy_production_solar);

            
            countryname = app.CountryNameDropDown.Value;
            selected_year = app.SelectyearSlider.Value;
            location_of_selected_year = find(ismember(year_data8,selected_year) & ismember(name_country,countryname));
            
            energy_hydro = energy_production_hydro(location_of_selected_year , 1);
            energy_wind = energy_production_wind(location_of_selected_year , 1);
            energy_solar = energy_production_solar(location_of_selected_year , 1);
            %putting the data into the tables.

            app.UITable.Data = [Tablevariable ; "Solar Production / [TWh]" , energy_solar ; "Hydro Production / [TWh]" , energy_hydro ; "Wind Production / [TWh]" , energy_wind];


intro_statement = " Providing Data regarding the solar energy for " + country_name ; 
if percent_change >= 100
    percent_increase = percent_change - 100;
    display_percent = country_name + " has produced " + percent_increase + "% more solar energy in 2019 when compared to the Worlds average.";
elseif percent_change < 100
    percent_decrease = 100 - percent_change;
    display_percent = country_name + " has produced " + percent_decrease + "% less solar energy in 2019 when compared to the Worlds average. ";
end

%hydro and wind comparision with solar.

hydro_compare = ceil(energy_hydro / energy_solar);
wind_compare = ceil(energy_wind / energy_solar);

if hydro_compare >= 1 
    hydro_statement = country_name + " has produced " + hydro_compare + " times more hydro energy when compared with solar energy production in " + selected_year;
else
    hydro_statement = "In the year " + selected_year + " the hydro energy production was significantly less than that of solar.";
end
if wind_compare >= 1
    wind_statement = country_name + " has produced " + wind_compare + " times more wind energy when compared with solar energy production in " + selected_year;
else
    wind_statement = "In the year " + selected_year + " the wind energy production was significantly less than that of solar.";
end

app.DisplayTextArea.Value = sprintf('%s \n \n %s \n %s \n %s' , intro_statement , display_percent , hydro_statement , wind_statement);

app.DisplayTextArea.Editable


%Graph plotting solar energy consumption in a specific country.

mask1 = (ismember(year_data8 , 2000) | ismember(year_data8 , 2001) | ismember(year_data8 , 2002) | ismember(year_data8 , 2003) |ismember(year_data8 , 2004) | ismember(year_data8 , 2005) |ismember(year_data8 , 2006) | ismember(year_data8 , 2007) |ismember(year_data8 , 2008) | ismember(year_data8 , 2009) |ismember(year_data8 , 2010) | ismember(year_data8 , 2011) |ismember(year_data8 , 2012) | ismember(year_data8 , 2013) |ismember(year_data8 , 2014) | ismember(year_data8 , 2015) |ismember(year_data8 , 2016) | ismember(year_data8 , 2017) |ismember(year_data8 , 2018) | ismember(year_data8 , 2019) );
mask2 = ismember( name_country , country_name);
mask3 = mask1 .* mask2;
x = [2000:1:2019];
mask3 = logical(mask3);
solar_production_plot = energy_production_solar(mask3);
solar_production_plot = solar_production_plot';
y = solar_production_plot;
scatter(app.UIAxes ,x,y , 'filled' )
xlabel(app.UIAxes ,"Years")
ylabel(app.UIAxes ,"TeraWatt Hour / [TWh]")
title(app.UIAxes ,'Renewable energy production')
grid(app.UIAxes , 'on')

%highlighting the value from the slider.
hold(app.UIAxes,'on')

loc_for_selected_year = find(ismember(year_data8 , app.SelectyearSlider.Value) & ismember(name_country,app.CountryNameDropDown.Value));
selectedval = energy_production_solar(loc_for_selected_year , 1);


xval = app.SelectyearSlider.Value ;

scatter(app.UIAxes , xval , selectedval , 'r' , 'filled' , 'd' )

hold(app.UIAxes , 'off')


        end

        % Value changed function: CountryNameDropDown
        function CountryNameDropDownValueChanged(app, event)
            value = app.CountryNameDropDown.Value;
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
%Creating the start botton 

%country names 
name_country = app.data3(:,1);
name_country = table2array(name_country);

%using function called unqiue the seprate all the countries
individual_country = unique(name_country);
app.CountryNameDropDown.Items = individual_country;


%slider value Eric
value = app.SelectyearSlider.Value;
app.SelectyearSlider.Limits = [2000 2019];


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Graph ')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SolarEfficentLampLabel = uilabel(app.UIFigure);
            app.SolarEfficentLampLabel.Text = 'Solar Efficent';
            app.SolarEfficentLamp = uilamp(app.UIFigure);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.DisplayTextAreaLabel = uilabel(app.UIFigure);
            app.DisplayTextAreaLabel.Text = 'Display';
            app.DisplayTextArea = uitextarea(app.UIFigure);
            app.DisplayTextArea.ValueChangedFcn = createCallbackFcn(app, @DisplayTextAreaValueChanged, true);
            app.DisplayTextArea.Editable = 'off';
            app.CountryNameDropDownLabel = uilabel(app.UIFigure);
            app.CountryNameDropDownLabel.Text = 'Country Name ';
            app.CountryNameDropDown = uidropdown(app.UIFigure);
            app.CountryNameDropDown.Items = {'Option 1', 'Option 2', 'Option 3', 'Option 5', 'Option 4'};
            app.CountryNameDropDown.ValueChangedFcn = createCallbackFcn(app, @CountryNameDropDownValueChanged, true);
            app.SelectyearSliderLabel = uilabel(app.UIFigure);
            app.SelectyearSliderLabel.Text = 'Select year';
            app.SelectyearSlider = uislider(app.UIFigure);
            app.TextArea2 = uitextarea(app.UIFigure);
            app.TextArea2.Value = {'NAE Group 10: Efficiency and Costs of Solar Energy Compared to Other Renewable Energy Sources'};
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.startButton = uibutton(app.UIFigure, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_outlook

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end